classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TrackImport                 matlab.ui.control.Button
        ImportTrackFIleLabel        matlab.ui.control.Label
        TyreImport                  matlab.ui.control.Button
        ImportTyreFIleLabel         matlab.ui.control.Label
        CarImport                   matlab.ui.control.Button
        ImportCarFIleLabel          matlab.ui.control.Label
        RunSimButton                matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        LaptimeSimulationToolLabel  matlab.ui.control.Label
        EditField                   matlab.ui.control.EditField
        EditField_2                 matlab.ui.control.EditField
        EditField_3                 matlab.ui.control.EditField
        ExitButton                  matlab.ui.control.Button
    end


    properties (Access = public)
        trackpath
        trackfile
        carpath
        carfile
        tyrepath
        tyrefile
        Output
        Results
        n = 1;
    end


    methods (Access = private)

        % Button pushed function: CarImport
        function CarImportPushed(app, event)
            [app.carfile,app.carpath] = uigetfile('*.mat');
        end

        % Button pushed function: RunSimButton
        function RunSim(app, event)
            clear Output Car Track Tyre
            %[app.Output] = main(app.trackpath,app.trackfile,app.carpath,app.carfile,app.tyrepath,app.tyrefile);
            %app.Results.time(app.n) = app.Output.laptime; 
            app.n = app.n+1;
            i = app.n;
            tabledata(i) = [i i+2];
            app.UITable.Data = tabledata;
        end

        % Button pushed function: TrackImport
        function TrackImportPushed(app, event)
            [app.trackfile,app.trackpath] = uigetfile('*.mat');
        end

        % Button pushed function: TyreImport
        function TyreImportPushed(app, event)
            [app.tyrefile,app.tyrepath] = uigetfile('*.mat');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TrackImport = uibutton(app.UIFigure, 'push');
            app.TrackImport.ButtonPushedFcn = createCallbackFcn(app, @TrackImportPushed, true);
            app.TrackImport.Text = 'Browse';
            app.ImportTrackFIleLabel = uilabel(app.UIFigure);
            app.ImportTrackFIleLabel.Text = 'Import Track FIle';
            app.TyreImport = uibutton(app.UIFigure, 'push');
            app.TyreImport.ButtonPushedFcn = createCallbackFcn(app, @TyreImportPushed, true);
            app.TyreImport.Text = 'Browse';
            app.ImportTyreFIleLabel = uilabel(app.UIFigure);
            app.ImportTyreFIleLabel.Text = 'Import Tyre FIle';
            app.CarImport = uibutton(app.UIFigure, 'push');
            app.CarImport.ButtonPushedFcn = createCallbackFcn(app, @CarImportPushed, true);
            app.CarImport.Text = 'Browse';
            app.ImportCarFIleLabel = uilabel(app.UIFigure);
            app.ImportCarFIleLabel.Text = 'Import Car FIle';
            app.RunSimButton = uibutton(app.UIFigure, 'push');
            app.RunSimButton.ButtonPushedFcn = createCallbackFcn(app, @RunSim, true);
            app.RunSimButton.Text = 'Run Sim';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Simulation'; 'Lap Time'; 'Simulation Time'};
            app.UITable.RowName = {};
            app.LaptimeSimulationToolLabel = uilabel(app.UIFigure);
            app.LaptimeSimulationToolLabel.Text = 'Lap time Simulation Tool';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.Text = 'Exit';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Main

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end